classdef poin_operation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        SetGray              matlab.ui.control.ToggleSwitch
        SetGrayLabel         matlab.ui.control.Label
        SetBinerSwitch       matlab.ui.control.ToggleSwitch
        SetBinerSwitchLabel  matlab.ui.control.Label
        NegativeSwitch       matlab.ui.control.ToggleSwitch
        NegativeSwitchLabel  matlab.ui.control.Label
        IntensitasKnob       matlab.ui.control.Knob
        IntensitasKnobLabel  matlab.ui.control.Label
        SelectImageButton_2  matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        histo_after          matlab.ui.control.UIAxes
        histo_ori            matlab.ui.control.UIAxes
        processing_image     matlab.ui.control.UIAxes
        ori_image            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        
        function results = tampilImage(app)
            global origImage;
            global intensitas;
            global negativeImage;
            global citraBiner;
            global citraGray;
            check = true;
            
            res = origImage;
            
            disp(intensitas);
            
            % Cek intensitas
            if ~isempty(intensitas)
               res = res + intensitas; 
            end
            
            % Cek Negative
            if negativeImage == "On"
                res = 255 - res; 
            end
            
            % Cek Biner / Grayscale
            if citraBiner == "On" 
                res = im2bw(res);
                check = false;
                grayImg = res;
            end
            
            if citraGray == "On" 
                res = rgb2gray(res);
                check = false;
                grayImg = res;
            end
            
            if check
               grayImg = rgb2gray(res); 
            end
            
            histogram(grayImg, 'Parent', app.histo_after);
            
            imshow(res,'Parent',app.processing_image);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton_2
        function SelectImageButton_2Pushed(app, event)
            global origImage;
            
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            origImage=imread(filename);
            
            grayImg = rgb2gray(origImage);
            histogram(grayImg, 'Parent', app.histo_ori);
            
            imshow(origImage,'Parent',app.ori_image);
            
        end

        % Value changing function: IntensitasKnob
        function IntensitasKnobValueChanging(app, event)
            global intensitas;
            intensitas = event.Value;
            
            tampilImage(app)
        end

        % Callback function
        function NegativeImageSwitchValueChanged(app, event)
            
        end

        % Callback function
        function ConvertNegativeImageButtonPushed(app, event)
            global afterImage;
            
            afterImage = 255 - afterImage;
            grayImg = rgb2gray(afterImage);
            histogram(grayImg, 'Parent', app.histo_after);
            
            imshow(afterImage,'Parent',app.processing_image);
        end

        % Callback function
        function ResetImageButtonPushed(app, event)
            global origImage;
            global afterImage;
            
            afterImage = origImage;
            
            grayImg = rgb2gray(afterImage);
            histogram(grayImg, 'Parent', app.histo_after);
            
            imshow(afterImage,'Parent',app.processing_image);
        end

        % Value changed function: IntensitasKnob
        function IntensitasKnobValueChanged(app, event)
            
           
        end

        % Value changed function: NegativeSwitch
        function NegativeSwitchValueChanged(app, event)
            global negativeImage;
            value = app.NegativeSwitch.Value;
            negativeImage = value;
            
            tampilImage(app);
        end

        % Value changed function: SetBinerSwitch
        function SetBinerSwitchValueChanged(app, event)
            value = app.SetBinerSwitch.Value;
            
            global citraBiner;
            global citraGray;
            
            citraBiner = value;
            if value == "On"
                app.SetGray.Value = "";
                citraGray = "";
            end
            
            tampilImage(app);
        end

        % Value changed function: SetGray
        function SetGrayValueChanged(app, event)
            value = app.SetGray.Value;
            
            global citraGray;
            global citraBiner;
            
            citraGray = value;
            if value == "On"
                app.SetBinerSwitch.Value = "";
                citraBiner = "";
            end
            
            tampilImage(app);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SelectImageButton_2 = uibutton(app.LeftPanel, 'push');
            app.SelectImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButton_2Pushed, true);
            app.SelectImageButton_2.Text = 'Select Image';
            app.IntensitasKnobLabel = uilabel(app.LeftPanel);
            app.IntensitasKnobLabel.Text = 'Intensitas';
            app.IntensitasKnob = uiknob(app.LeftPanel, 'continuous');
            app.IntensitasKnob.Limits = [-100 100];
            app.IntensitasKnob.ValueChangedFcn = createCallbackFcn(app, @IntensitasKnobValueChanged, true);
            app.IntensitasKnob.ValueChangingFcn = createCallbackFcn(app, @IntensitasKnobValueChanging, true);
            app.NegativeSwitchLabel = uilabel(app.LeftPanel);
            app.NegativeSwitchLabel.Text = 'Negative';
            app.NegativeSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.NegativeSwitch.ValueChangedFcn = createCallbackFcn(app, @NegativeSwitchValueChanged, true);
            app.SetBinerSwitchLabel = uilabel(app.LeftPanel);
            app.SetBinerSwitchLabel.Text = 'Set Biner';
            app.SetBinerSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.SetBinerSwitch.Items = {'', 'On'};
            app.SetBinerSwitch.ValueChangedFcn = createCallbackFcn(app, @SetBinerSwitchValueChanged, true);
            app.SetBinerSwitch.Value = '';
            app.SetGrayLabel = uilabel(app.LeftPanel);
            app.SetGrayLabel.Text = 'Set Gray';
            app.SetGray = uiswitch(app.LeftPanel, 'toggle');
            app.SetGray.Items = {'', 'On'};
            app.SetGray.ValueChangedFcn = createCallbackFcn(app, @SetGrayValueChanged, true);
            app.SetGray.Value = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.ori_image = uiaxes(app.RightPanel);
            title(app.ori_image, 'Original Image')
            app.ori_image.XTick = [];
            app.ori_image.YTick = [];
            app.processing_image = uiaxes(app.RightPanel);
            title(app.processing_image, 'After Processing')
            app.processing_image.XTick = [];
            app.processing_image.YTick = [];
            app.histo_ori = uiaxes(app.RightPanel);
            title(app.histo_ori, 'Histogram Original')
            app.histo_after = uiaxes(app.RightPanel);
            title(app.histo_after, 'Histogram After Processing')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poin_operation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end